classdef Authentication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        Panel                 matlab.ui.container.Panel
        RaspberryPiosLabel    matlab.ui.control.Label
        LoginstatusLabel      matlab.ui.control.Label
        StatusLabelRaspberry  matlab.ui.control.Label
        PASSWORDLabel         matlab.ui.control.Label
        USERNAMELabel         matlab.ui.control.Label
        PasswordField         matlab.ui.control.EditField
        UsernameField         matlab.ui.control.EditField
        StatusLabel           matlab.ui.control.Label
        LogInButton           matlab.ui.control.Button
        AUTHENTICATIONLabel   matlab.ui.control.Label
        Tab2                  matlab.ui.container.Tab
        Panel2                matlab.ui.container.Panel
        allLabel              matlab.ui.control.Label
        ArduinoChooserLabel   matlab.ui.control.Label
        LogFileDropDown       matlab.ui.control.DropDown
        Tab3                  matlab.ui.container.Tab
        UIAxes4               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
        Tab3_2                matlab.ui.container.Tab
        UIAxes4_2             matlab.ui.control.UIAxes
        UIAxes3_2             matlab.ui.control.UIAxes
        UIAxes2_2             matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
    end

    properties (Access = private)
        ValidUsername = 'Susan Luna';
        ValidPassword = '12345';
        Raspberry rosdevice;        
        ContinueLoop logical = true;
    end
    
    methods (Access = private)
        
        function retrieveLogs(app, selectedLogFile)
            localFile = fullfile(tempdir, 'logs_last30.log'); 
            
            try
                last30LinesCommand = ['tail -n 30 ', selectedLogFile, ' > ', selectedLogFile, '.tmp'];
                run(app.Raspberry, last30LinesCommand); 
                
                scp(app.Raspberry, [selectedLogFile, '.tmp'], localFile);

                if isfile(localFile)
                    app.ArduinoChooserLabel.Text = "Logs retrieved successfully at " + datestr(now);
                else
                    error("Failed to transfer log file.");
                end
            catch ME
                app.ArduinoChooserLabel.Text = "Error: " + ME.message;
                app.ContinueLoop = false; 
            end

            pause(5); 
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LogInButton
        function LogInButtonPushed(app, event)
            enteredUsername = app.UsernameField.Value;
            enteredPassword = app.PasswordField.Value;
        
            % Validate 
            if strcmp(enteredUsername, app.ValidUsername) && strcmp(enteredPassword, app.ValidPassword)
                app.StatusLabel.Text = 'Login successful!';
                app.StatusLabel.FontColor = [0, 0.5, 0]; % Green
    
                if isempty(app.Raspberry) || strcmp(app.Raspberry, '') 
                    try
                        app.Raspberry = rosdevice('192.168.0.1', 'susan', '12345');
                        disp('Connected to Raspberry Pi successfully.');
                        app.StatusLabelRaspberry.Text = "Connected to Raspberry Pi successfully.";
                        app.StatusLabelRaspberry.FontColor = [0, 0.5, 0]; % Green
                    catch ME
                        uialert(app.UIFigure, 'Failed to connect to Raspberry Pi!', 'Connection Error');
                        disp(ME.message);
                        app.StatusLabelRaspberry.Text = "Failed to connect to Raspberry Pi!";
                        app.StatusLabelRaspberry.FontColor = [1, 0, 0]; % Red
                    end
                else
                    disp('Already connected to Raspberry Pi.');
                    app.StatusLabelRaspberry.Text = "Already connected to Raspberry Pi.";
                    app.StatusLabelRaspberry.FontColor = [0, 0.5, 0]; % Green
                end
            else
                % Invalid credentials
                app.StatusLabel.Text = 'Invalid credentials. Try again.';
                app.StatusLabel.FontColor = [1, 0, 0]; % Red
                app.StatusLabelRaspberry.Text = "Log in correctly to check the connection.";
                app.StatusLabelRaspberry.FontColor = [0, 0, 0]; % Black
            end
        end

        % Callback function
        function LogInButtonPushed2(app, event)
            
        end

        % Value changed function: LogFileDropDown
        function LogFileDropDownValueChanged(app, event)

            selectedOption = app.LogFileDropDown.Value;

            switch selectedOption
                case 'Arduino 1'
                    selectedLogFile = '/home/susan/Documents/logs_arduino_1.log';
                case 'Arduino 2'
                    selectedLogFile = '/home/susan/Documents/logs_arduino_2.log';
                otherwise
                    app.ArduinoChooserLabel.Text = "Error: Invalid selection.";
                    return; 
            end
        
            app.ArduinoChooserLabel.Text = "Retrieving logs from: " + selectedLogFile;
  
            app.ContinueLoop = false; 
            pause(1); 
            app.ContinueLoop = true; 
        
            retrieveLogs(app, selectedLogFile);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = 'Panel';
            app.AUTHENTICATIONLabel = uilabel(app.Panel);
            app.AUTHENTICATIONLabel.Text = 'AUTHENTICATION';
            app.LogInButton = uibutton(app.Panel, 'push');
            app.LogInButton.ButtonPushedFcn = createCallbackFcn(app, @LogInButtonPushed, true);
            app.LogInButton.Text = 'LOG IN ';
            app.StatusLabel = uilabel(app.Panel);
            app.StatusLabel.Text = '';
            app.UsernameField = uieditfield(app.Panel, 'text');
            app.PasswordField = uieditfield(app.Panel, 'text');
            app.USERNAMELabel = uilabel(app.Panel);
            app.USERNAMELabel.Text = 'USERNAME:';
            app.PASSWORDLabel = uilabel(app.Panel);
            app.PASSWORDLabel.Text = 'PASSWORD:';
            app.StatusLabelRaspberry = uilabel(app.Panel);
            app.StatusLabelRaspberry.Text = '';
            app.LoginstatusLabel = uilabel(app.Panel);
            app.LoginstatusLabel.Text = 'Log in status:';
            app.RaspberryPiosLabel = uilabel(app.Panel);
            app.RaspberryPiosLabel.Text = 'Raspberry Pi os:';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Panel2 = uipanel(app.Tab2);
            app.Panel2.Title = 'Panel2';
            app.LogFileDropDown = uidropdown(app.Panel2);
            app.LogFileDropDown.Items = {'Arduino 1', 'Arduino 2'};
            app.LogFileDropDown.ValueChangedFcn = createCallbackFcn(app, @LogFileDropDownValueChanged, true);
            app.LogFileDropDown.Value = 'Arduino 1';
            app.ArduinoChooserLabel = uilabel(app.Panel2);
            app.allLabel = uilabel(app.Panel2);
            app.allLabel.Text = 'Label2';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes = uiaxes(app.Tab3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.Tab3);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.Tab3);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.Tab3);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Tab3_2 = uitab(app.TabGroup);
            app.Tab3_2.Title = 'Tab3';
            app.UIAxes_2 = uiaxes(app.Tab3_2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.Tab3_2);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes3_2 = uiaxes(app.Tab3_2);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes4_2 = uiaxes(app.Tab3_2);
            title(app.UIAxes4_2, 'Title')
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            zlabel(app.UIAxes4_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Authentication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end